classdef rowingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ReconnectButton          matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        PollButton               matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        MeterSecGaugeLabel       matlab.ui.control.Label
        MeterSecGauge            matlab.ui.control.LinearGauge
        StrokesMinuteGaugeLabel  matlab.ui.control.Label
        StrokesMinuteGauge       matlab.ui.control.LinearGauge
        Panel2                   matlab.ui.container.Panel
        DistanceLabel            matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        TimeLabel                matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        TotalDistanceLabel       matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        mLabel                   matlab.ui.control.Label
    end


    properties (Access = public)
        rower % S4 rowing computer interface
        connected
        message
        value
        pulseCount
        polltimer
        messages ={'1E1' '055' '148'};
        nextmessage = 1;
        indx =1;
        logtime =duration(0,0,0);
        logdistance;
        usertotal;
    end

    methods (Access = private)
    
        function connect(app)
            if app.rower.connect
                app.ReconnectButton.Visible = 'on';
                app.ReconnectButton.Visible = 'off';
                app.ReconnectButton.Enable = 'off';
            else
                app.ReconnectButton.Visible = 'on';
                app.ReconnectButton.Enable = 'on';
                msgbox('Please Connect S4 Rower then Reconnect')
            end
            
        end
        
    end

    methods (Access = public)
    
        function setDistance(app,distance)
            app.Label_2.Text = distance;
            
        end
        
        function SetStrokesPerMin(app,strokes)
            app.StrokesMinuteGauge.Value= strokes;
            app.StrokesMinuteGaugeLabel.Text= strcat('Strokes/Minute: ',string(strokes));            
        end
    
        function SetMeterPerMin(app,dist)
            app.MeterSecGauge.Value = dist;
            app.MeterSecGaugeLabel.Text = strcat('Meter/Sec: ',string(dist));
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rower = WaterRowerS4('COM6',app);
            app.connect();   
            app.connected = 2;
            app.polltimer  = timer;
            app.polltimer.ExecutionMode = 'fixedSpacing';
            app.polltimer.Period = 0.25;
            app.polltimer.Tag = 'WaterRower';
            app.polltimer.TimerFcn = {@pollSignals,app};
            app.usertotal = load('gregtotal.mat','total');
            app.Label_3.Text = num2str(app.usertotal.total);
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            app.connect();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             app.rower.shutdown;
            delete( instrfind('Tag','WaterRower'));
            delete(timerfind('Tag','WaterRower'));
            delete(app)
            
        end

        % Button pushed function: PollButton
        function PollButtonPushed(app, event)
            switch app.PollButton.Text%app.polltimer.Running
                case 'Save'
                    stop(app.polltimer)
                    time = app.logtime;
                    distance = app.logdistance;
                    date = datetime('now');
                    datestring = strcat(string(date.Month),'_',string(date.Day),'_',string(date.Year),'_',string(date.Hour),'_',string(date.Minute));
                    filename = strcat('C:\Users\Greg\Documents\MATLAB\S4Rower\gregs_sessions\rowingsession_',datestring,'.mat');
                    save(filename,'time','distance')
                    total = distance(end) + app.usertotal.total;
                    app.Label_3.Text = num2str(total);
                    save('gregtotal.mat','total');
                    app.PollButton.Text = 'Reset';
                case 'Reset'
                    app.rower.reset();
                    app.PollButton.Text = 'Poll';
                case 'Poll'
                    start(app.polltimer)
                    app.PollButton.Text = 'Save';
            end
            %fprintf(app.rower.USB,'IRD1E1');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ReconnectButton = uibutton(app.UIFigure, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Meters')
            app.PollButton = uibutton(app.UIFigure, 'push');
            app.PollButton.ButtonPushedFcn = createCallbackFcn(app, @PollButtonPushed, true);
            app.PollButton.Text = 'Poll';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.MeterSecGaugeLabel = uilabel(app.Panel);
            app.MeterSecGaugeLabel.Text = 'Meter/Sec';
            app.MeterSecGauge = uigauge(app.Panel, 'linear');
            app.MeterSecGauge.Limits = [0 6];
            app.StrokesMinuteGaugeLabel = uilabel(app.Panel);
            app.StrokesMinuteGaugeLabel.Text = 'Strokes/Minute';
            app.StrokesMinuteGauge = uigauge(app.Panel, 'linear');
            app.StrokesMinuteGauge.Limits = [0 50];
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.DistanceLabel = uilabel(app.Panel2);
            app.DistanceLabel.Text = 'Distance';
            app.Label_2 = uilabel(app.Panel2);
            app.Label_2.Text = '000000';
            app.TimeLabel = uilabel(app.Panel2);
            app.TimeLabel.Text = 'Time';
            app.Label = uilabel(app.Panel2);
            app.Label.Text = '00:00:00';
            app.TotalDistanceLabel = uilabel(app.UIFigure);
            app.TotalDistanceLabel.Text = 'Total Distance:';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0000000';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rowingGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end